classdef MultiJump < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        NewMenu             matlab.ui.container.Menu
        OpenMenu            matlab.ui.container.Menu
        SaveMenu            matlab.ui.container.Menu
        QuitMenu            matlab.ui.container.Menu
        Label_iSub          matlab.ui.control.Label
        NameText            matlab.ui.control.TextArea
        Label_3             matlab.ui.control.Label
        Spinner             matlab.ui.control.Spinner
        Label_2             matlab.ui.control.Label
        TimingButtonGroup   matlab.ui.container.ButtonGroup
        SmartphoneButton    matlab.ui.control.RadioButton
        ImmediateButton     matlab.ui.control.RadioButton
        UITable             matlab.ui.control.Table
        MatlabMobileButton  matlab.ui.control.Button
        Label               matlab.ui.control.Label
        ButtonStart         matlab.ui.control.Button
        JumpButtonGroup     matlab.ui.container.ButtonGroup
        RJButton            matlab.ui.control.RadioButton
        DJButton            matlab.ui.control.RadioButton
        CMJButton           matlab.ui.control.RadioButton
        UIAxes              matlab.ui.control.UIAxes
    end

        
    properties (Access = private)
        m % Description
        iSub
        Dat
        tbl
    end
       
    methods (Access = private)
        
        function DropJump(app)
            
            %% 変数の読み込み
            m = app.m;
            discardlogs(m); % ログデータの初期化
            iSub = app.iSub;
            Dat = app.Dat;
            tbl = app.tbl;
            
            app.Label_iSub.Text = string(app.iSub);
            
            %% 初期設定
            % 加速度(Acceleration):[m/s^2]
            % 角速度(AngularVelocity):[rad/s]
            % 磁場(Magnetic):[μT]
            % 方向(Orientation):[方位、傾斜、回転　deg]
            accel = [];
            
            m.SampleRate = 'high';
            m.AccelerationSensorEnabled = 1;
            m.AngularVelocitySensorEnabled = 1;
            m.OrientationSensorEnabled = 1;
            m.MagneticSensorEnabled = 0;
            m.PositionSensorEnabled = 0;
            
            loggingTime = app.Spinner.Value; % seconds
            Fs = m.SampleRate;
            app.Label.Text = "動作を開始して下さい。";
            
            i = 1;
            m.Logging = 1;
            tic;
            while toc < loggingTime
                if  isempty(app.m.Orientation) ~= 1
                    ac = m.Acceleration;
                    accel(i,:) = ac;
                    t(i) = toc;
                    plot(app.UIAxes,t,accel);
                    drawnow
                    i = i + 1;
                end
            end
            m.Logging = 0;

            %% スマートフォンからデータログを抽出
            [acc, ta] = accellog(m);
            [gyro, tg] = angvellog(m);
            [orien, to] = orientlog(m);
            
            % データ数を一致させる
            [tt,acc,gyro,orien] = MatchLength(ta,acc,gyro,orien);
            
            %% データ処理
            % Acc,Gyroからクォータニオンを算出
            q = MadgwickFilter(acc,gyro,0,Fs,0.4);
            quat = quaternion(q);
            euler = eulerd(quat,'ZXY','frame');
            acc_global = quaternRotate(acc, compact(quat));
            acc_linear = acc_global;
            acc_linear(:,3) = acc_linear(:,3) - 9.80665;
            
            stationary = zeros(length(acc(:,1)),1);
            stationary(1) = 1;
            stationary(end) = 1;
            vel_sta = drift_correction(acc_linear,Fs,stationary);
            
            %% ドロップジャンプに関する指標を算出
            acc_z = acc_linear(:,3);
            vel_z = vel_sta(:,3);
            Jump = CalcDropJump(acc_z,vel_z,Fs);
            tc = Jump.tc;
            ta = Jump.ta;
            height = Jump.height;
            dj_index = Jump.dj_index;
 
            %% プロット
            plot(app.UIAxes,tt,acc_global);
            
            %% データの格納
            Dat(iSub).tt = tt;
            Dat(iSub).acc = acc;
            Dat(iSub).gyro = gyro;
            Dat(iSub).acc_linear = acc_linear;
            Dat(iSub).vel_sta = vel_sta;
            Dat(iSub).euler = euler;
            Dat(iSub).Jump = Jump;
            app.Dat = Dat;
            
            % テーブルの作成
            tbl.No(iSub) = iSub;
            tbl.fname(iSub) = app.NameText.Value;
            tbl.tc(iSub) = tc;
            tbl.ta(iSub) = ta;
            tbl.height(iSub) = height;
            tbl.dj_index(iSub) = dj_index;
            
            % テーブルをアプリに反映
            app.UITable.Data = tbl;
            app.tbl = tbl;
            
            % データをcsvで保存
            IMU = table();
            IMU.tt = tt;
            IMU.ax = acc(:,1);
            IMU.ay = acc(:,2);
            IMU.az = acc(:,3);
            IMU.gx = gyro(:,1);
            IMU.gy = gyro(:,2);
            IMU.gz = gyro(:,3);
            IMU.az_g = acc_z;
            IMU.vz_g = vel_z;
            Jump = table();
            Jump.tc = tc;
            Jump.ta = ta;
            Jump.height = height;
            Jump.dj_index = dj_index;
            dt = datetime('now');
            DateString = datestr(dt,'yyyyMMddHHmmssFFF');
            filename = ['./xlsx/' char(app.NameText.Value) '_dj_' DateString '.xlsx'];
            writetable(IMU,filename,'Sheet','sensor_dat','Range','A1');
            writetable(Jump,filename,'Sheet','index','Range','A1');
            
            % 次の測定の準備
            app.iSub = app.iSub + 1;
                        
        end
        
        function SetDev(app)
            %% デバイスの設定
            m = mobiledev;
            app.m = m;
            app.iSub = 1;
            app.Label_iSub.Text = string(app.iSub);
            app.tbl = table();
            app.ButtonStart.Enable = 1;
        end
                
        function CounterMovementJump(app)
            %% 変数の読み込み
            m = app.m;
            discardlogs(m); % ログデータの初期化
            iSub = app.iSub;
            Dat = app.Dat;
            tbl = app.tbl;
            
            app.Label_iSub.Text = string(app.iSub);
            
            %% 初期設定
            % 加速度(Acceleration):[m/s^2]
            % 角速度(AngularVelocity):[rad/s]
            % 磁場(Magnetic):[μT]
            % 方向(Orientation):[方位、傾斜、回転　deg]
            accel = [];
            orie = [];
            
            m.SampleRate = 'high';
            m.AccelerationSensorEnabled = 1;
            m.AngularVelocitySensorEnabled = 1;
            m.OrientationSensorEnabled = 1;
            m.MagneticSensorEnabled = 0;
            m.PositionSensorEnabled = 0;
            
            loggingTime = app.Spinner.Value; % seconds
            Fs = m.SampleRate;
            app.Label.Text = "動作を開始して下さい。";
            
            i = 1;
            m.Logging = 1;
            tic;
            while toc < loggingTime
                if  isempty(app.m.Orientation) ~= 1
                    ac = m.Acceleration;
                    accel(i,:) = ac;
                    t(i) = toc;
                    plot(app.UIAxes,t,accel);
                    drawnow
                    i = i + 1;
                end
            end
            m.Logging = 0;

            %% スマートフォンからデータログを抽出
            [acc, ta] = accellog(m);
            [gyro, tg] = angvellog(m);
            [orien, to] = orientlog(m);
            
            % データ数を一致させる
            [tt,acc,gyro,orien] = MatchLength(ta,acc,gyro,orien);
            
            %% データ処理
            % Acc,Gyroからクォータニオンを算出
            q = MadgwickFilter(acc,gyro,0,Fs,0.4);
            quat = quaternion(q);
            euler = eulerd(quat,'ZXY','frame');
            acc_global = quaternRotate(acc, compact(quat));
            acc_linear = acc_global;
            acc_linear(:,3) = acc_linear(:,3) - 9.80665;
            
            stationary = zeros(length(acc(:,1)),1);
            stationary(1) = 1;
            stationary(end) = 1;
            vel_sta = drift_correction(acc_linear,Fs,stationary);
            
            %% ドロップジャンプに関する指標を算出
            acc_z = acc_linear(:,3);
            vel_z = vel_sta(:,3);
            Jump = CalcCMJ(acc_z,vel_z,Fs);
            tc = Jump.tc;
            ta = Jump.ta;
            height = Jump.height;
            dj_index = Jump.dj_index;
 
            %% プロット
            plot(app.UIAxes,tt,acc_global);
            
            %% データの格納
            Dat(iSub).tt = tt;
            Dat(iSub).acc = acc;
            Dat(iSub).gyro = gyro;
            Dat(iSub).acc_linear = acc_linear;
            Dat(iSub).vel_sta = vel_sta;
            Dat(iSub).euler = euler;
            Dat(iSub).Jump = Jump;
            app.Dat = Dat;
            
            % テーブルの作成
            tbl.No(iSub) = iSub;
            tbl.fname(iSub) = app.NameText.Value;
            tbl.tc(iSub) = tc;
            tbl.ta(iSub) = ta;
            tbl.height(iSub) = height;
            tbl.dj_index(iSub) = dj_index;
            
            % テーブルをアプリに反映
            app.UITable.Data = tbl;
            app.tbl = tbl;
            
            % データをcsvで保存
            IMU = table();
            IMU.tt = tt;
            IMU.ax = acc(:,1);
            IMU.ay = acc(:,2);
            IMU.az = acc(:,3);
            IMU.gx = gyro(:,1);
            IMU.gy = gyro(:,2);
            IMU.gz = gyro(:,3);
            IMU.az_g = acc_z;
            IMU.vz_g = vel_z;
            Jump = table();
            Jump.tc = tc;
            Jump.ta = ta;
            Jump.height = height;
            Jump.dj_index = dj_index;
            dt = datetime('now');
            DateString = datestr(dt,'yyyyMMddHHmmssFFF');
            filename = ['./xlsx/' char(app.NameText.Value) '_cmj_' DateString '.xlsx'];
            writetable(IMU,filename,'Sheet','sensor_dat','Range','A1');
            writetable(Jump,filename,'Sheet','index','Range','A1');
            
            % 次の測定の準備
            app.iSub = app.iSub + 1;
        end
        
        function ReboundJump(app)
            app.Label.Text = "未実装";
        end

        function OpenDat(app)
            [file, path] = uigetfile('./mat/*.mat');
            if file==0
                return
            end
            load([path file]);
            app.Dat = Dat;
            app.tbl = tbl;
            % テーブルをアプリに反映
            app.UITable.Data = tbl;
        end
                
        function SaveDat(app)
            [file, path] = uiputfile('./mat/*.mat');
            if file==0
                return
            end
            Dat = app.Dat;
            tbl = app.tbl;
            save([path file],'Dat','tbl');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonStart
        function MeasurementStart(app, event)
            app.Label.Text = "しばらくお待ち下さい。";
            pause(0.1);
            if app.CMJButton.Value == 1
                CounterMovementJump(app);
            elseif app.DJButton.Value == 1
                DropJump(app);
            elseif app.RJButton.Value == 1
                ReboundJump(app);
            end
            app.Label.Text = "測定開始ボタンを押して下さい。";
        end

        % Button pushed function: MatlabMobileButton
        function MatlabMobileButtonPushed(app, event)
            app.Label.Text = "接続中。しばらくお待ち下さい。";
            SetDev(app);
            app.Label.Text = "接続完了。測定開始ボタンを押して下さい。";
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            SaveDat(app);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            OpenDat(app);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            t = table();
            app.UITable.Data = t;
            clearvars -except app.m
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'ファイル';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = '新規';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'matファイルを開く';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'matファイルを保存';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'アプリの終了';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'グローバル加速度')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Acceleration [m/s^2]')
            zlabel(app.UIAxes, 'Z')
            app.JumpButtonGroup = uibuttongroup(app.UIFigure);
            app.JumpButtonGroup.Title = '測定種目';
            app.CMJButton = uiradiobutton(app.JumpButtonGroup);
            app.CMJButton.Text = 'CMJ(ｶｳﾝﾀｰﾑｰﾌﾞﾒﾝﾄｼﾞｬﾝﾌﾟ)';
            app.DJButton = uiradiobutton(app.JumpButtonGroup);
            app.DJButton.Text = 'DJ(ﾄﾞﾛｯﾌﾟｼﾞｬﾝﾌﾟ)';
            app.DJButton.Value = true;
            app.RJButton = uiradiobutton(app.JumpButtonGroup);
            app.RJButton.Text = 'RJ(ﾘﾊﾞｳﾝﾄﾞｼﾞｬﾝﾌﾟ)';
            app.ButtonStart = uibutton(app.UIFigure, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @MeasurementStart, true);
            app.ButtonStart.Enable = 'off';
            app.ButtonStart.Text = '測定開始';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Matlab Mobileに接続して下さい。';
            app.MatlabMobileButton = uibutton(app.UIFigure, 'push');
            app.MatlabMobileButton.ButtonPushedFcn = createCallbackFcn(app, @MatlabMobileButtonPushed, true);
            app.MatlabMobileButton.Text = 'Matlab Mobileに接続';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No'; '名前'; '接地時間'; '滞空時間'; '跳躍高'; 'DJ-index'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.TimingButtonGroup = uibuttongroup(app.UIFigure);
            app.TimingButtonGroup.Title = '開始のタイミング';
            app.ImmediateButton = uiradiobutton(app.TimingButtonGroup);
            app.ImmediateButton.Text = '即時';
            app.ImmediateButton.Value = true;
            app.SmartphoneButton = uiradiobutton(app.TimingButtonGroup);
            app.SmartphoneButton.Text = 'スマートフォン';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '測定時間';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 10;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '名前';
            app.NameText = uitextarea(app.UIFigure);
            app.NameText.Tag = '0';
            app.Label_iSub = uilabel(app.UIFigure);
            app.Label_iSub.Text = 'iSub';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MultiJump

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end